classdef edu_course_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATLABUIFigure  matlab.ui.Figure
        Install         matlab.ui.control.Button
        Label           matlab.ui.control.Label
        Start           matlab.ui.control.Button
        Store           matlab.ui.control.Button
        VerLabel        matlab.ui.control.Label
        UpdateLabel     matlab.ui.control.Label
        Update          matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function wb = waitbar(app, title)
            wb = uiprogressdlg(app.MATLABUIFigure, 'Title', title, 'Indeterminate', 'on');
        end
        
        function error(app, msg)
            uialert(app.MATLABUIFigure, msg, 'Ошибка');
        end
        
        function done(app, msg)
            uialert(app.MATLABUIFigure, msg, 'Готово', 'Icon', 'success');
        end
    end
    

    methods (Access = private)

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            addEduCourseToPath;
            open('edu_course_content.mlx')
        end

        % Button pushed function: Install
        function InstallButtonPushed(app, event)
            wb = app.waitbar('Копирование файлов...');
            pause(1)
            close(wb);
        end

        % Button pushed function: Store
        function StoreButtonPushed(app, event)
            dirname = "EduCourseFiles";
            [~, cdname] = fileparts(pwd);
            if cdname == dirname
                cd ..
            end
            if isfolder(dirname)
                wb = app.waitbar('Архивация файлов...');
                fs = dir(dirname);
                fs = fs(3:end);
                fs = {fs.name};
                zip(dirname, fs, dirname);
                close(wb);
                app.done("Файлы занятий помещены в архив " + dirname + ".zip");
            else
                app.error("Папка 'EduCourseFiles' не найдена");
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATLABUIFigure = uifigure;
            app.MATLABUIFigure.Name = 'Курс "MATLAB для преподавателей"';
            app.Install = uibutton(app.MATLABUIFigure, 'push');
            app.Install.ButtonPushedFcn = createCallbackFcn(app, @InstallButtonPushed, true);
            app.Install.Icon = 'icon-install.png';
            app.Install.Text = 'Установить курс в текущую папку';
            app.Label = uilabel(app.MATLABUIFigure);
            app.Label.Text = 'Текущий прогресс будет удален';
            app.Start = uibutton(app.MATLABUIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Icon = 'icon-start.png';
            app.Start.Text = 'Приступить';
            app.Store = uibutton(app.MATLABUIFigure, 'push');
            app.Store.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPushed, true);
            app.Store.Icon = 'icon-store.png';
            app.Store.Text = 'Заархивировать файлы';
            app.VerLabel = uilabel(app.MATLABUIFigure);
            app.VerLabel.Text = 'Версия 0.1';
            app.UpdateLabel = uilabel(app.MATLABUIFigure);
            app.UpdateLabel.Visible = 'off';
            app.UpdateLabel.Text = 'Доступно обновление';
            app.Update = uibutton(app.MATLABUIFigure, 'push');
            app.Update.Icon = 'icon-install.png';
            app.Update.Visible = 'off';
            app.Update.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = edu_course_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATLABUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATLABUIFigure)
        end
    end
end